classdef GUI_login_brugergraense < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        VelkommentilUTreatmentLabel  matlab.ui.control.Label
        EditField                    matlab.ui.control.EditField
        EditField_2                  matlab.ui.control.EditField
        LoginButton                  matlab.ui.control.Button
        ForketeloginoplysningerindtastpnyLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ForketeloginoplysningerindtastpnyLabel.Visible = 'off';
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
           
           % Omdøb værdierne fra feltet brugernavn og adgangskode
           % til hhv. usernam og password
           username = app.EditField.Value;
           password = app.EditField_2.Value;
            
           if username == '1' && password == '1'
                GUI_vaelg_brug
                app.UIFigure.Visible = 'off';  
           else
                app.ForketeloginoplysningerindtastpnyLabel.Visible = 'on';
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VelkommentilUTreatmentLabel = uilabel(app.UIFigure);
            app.VelkommentilUTreatmentLabel.Text = {'Velkommen til UTreatment'; ''};
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'Brugernavn';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.Value = 'Adgangskode';
            app.LoginButton = uibutton(app.UIFigure, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = {'Login'; ''};
            app.ForketeloginoplysningerindtastpnyLabel = uilabel(app.UIFigure);
            app.ForketeloginoplysningerindtastpnyLabel.Text = 'Forkete loginoplysninger - indtast på ny';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_login_brugergraense

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end