classdef GUI_ny_patient < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LogudButton                     matlab.ui.control.Button
        MenuButton                      matlab.ui.control.Button
        OprettelseafnypatientLabel      matlab.ui.control.Label
        SwitchGender                    matlab.ui.control.Switch
        KnLabel                         matlab.ui.control.Label
        TrangLabel                      matlab.ui.control.Label
        AntaldagligeToiletbesgSliderLabel  matlab.ui.control.Label
        AntaldagligeToiletbesgSlider    matlab.ui.control.Slider
        InkontinensSliderLabel          matlab.ui.control.Label
        InkontinensSlider               matlab.ui.control.Slider
        AntalnatligeToiletbesgSliderLabel  matlab.ui.control.Label
        AntalnatligeToiletbesgSlider    matlab.ui.control.Slider
        Label                           matlab.ui.control.Label
        VisanbefalingButton             matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
        GemButton                       matlab.ui.control.Button
        StimulationsanbefalingLabel     matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        TrangparadigmeLabel             matlab.ui.control.Label
        TidsbegrnsetparadigmeLabel      matlab.ui.control.Label
        IntensitettrangLabel            matlab.ui.control.Label
        SessionstidLabel                matlab.ui.control.Label
        IntensitettidsbegrnsetLabel     matlab.ui.control.Label
        SwitchUrge                      matlab.ui.control.Switch
        Label_8                         matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        VisAnbefaling1                  matlab.ui.control.NumericEditField
        VisAnbefaling2                  matlab.ui.control.NumericEditField
        VisAnbefaling3                  matlab.ui.control.NumericEditField
        VisAnbefaling4                  matlab.ui.control.NumericEditField
        VisAnbefaling5                  matlab.ui.control.NumericEditField
        CPRnrEditFieldLabel             matlab.ui.control.Label
        CPRnrEditField                  matlab.ui.control.EditField
        SpinnerAntalDagligeToiletbesog  matlab.ui.control.Spinner
        SpinnerAntalInkontinensEpisoder  matlab.ui.control.Spinner
        SpinnerAntalNatligeToiletbesog  matlab.ui.control.Spinner
        AlderEditField_2Label           matlab.ui.control.Label
        AlderEditField                  matlab.ui.control.EditField
        BMIEditField_2Label             matlab.ui.control.Label
        BMIEditField                    matlab.ui.control.EditField
    end

    
    properties (Access = public)
        % Forbindelse til databasen
        conn
        data
        dataMidlertidig
        
        %Værdier fra slider
        latestValueSliderAntalDagligeToiletbesog
        latestValueSliderInkontinensEpisoder
        latestValueSliderAntalNatligeToiletbesog
        
        % Indtastede patientinformationer
        CPR
        x_Age
        x_Gender
        x_BMI
        x_Urge
        x_Frequency
        x_Leakage  
        x_Nocturia 
        
        % Resultater af modeller
        YFIT_1 
        YFIT_2
        YFIT_3
        YFIT_4
        YFIT_5
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.VisAnbefaling1.Visible = 'off';
            app.VisAnbefaling2.Visible = 'off';
            app.VisAnbefaling3.Visible = 'off';
            app.VisAnbefaling4.Visible = 'off';
            app.VisAnbefaling5.Visible = 'off';
            
            % Opret forbindelse til databasen
            app.conn = database('hst_2022st6403','hst_2022st6403','gaevaepheimeicoogexe'); % OBS: første streng er det navn, man selv har givet databasen ved oprettelsen

            % Træk data fra algoritme databasen
            selectquery = 'SELECT * FROM AlgoritmeDatabase';
            app.data = select(app.conn,selectquery);

            % Træk data fra den midlertidige database
            selectquery = 'SELECT * FROM MidlertidigDatabase';
            app.dataMidlertidig = select(app.conn,selectquery);
            
        end

        % Button pushed function: LogudButton
        function LogudButtonPushed(app, event)
            qans = questdlg('Er du sikker på, at du vil logge ud?','Log ud','Ja','Nej','Ja');

            if isempty(qans)|| strcmp(qans, 'Ja')
                GUI_login_brugergraense
                app.UIFigure.Visible = 'off';
                return
            end

        end

        % Button pushed function: MenuButton
        function MenuButtonPushed(app, event)
            qans = questdlg('Er du sikker på, at du vil gå til menu?','Tilbage til menu','Ja','Nej','Ja');

            if isempty(qans)|| strcmp(qans, 'Ja')
                GUI_vaelg_brug
                app.UIFigure.Visible = 'off';
                return
            end

        end

        % Callback function
        function UndladatgemmeButtonPushed(app, event)
            qans = questdlg('Er du sikker på, at du ikke vil gemme?','Gem patient','Ja','Nej','Nej');

            if isempty(qans)|| strcmp(qans, 'Nej')
                return
            end
        end

        % Value changing function: AntaldagligeToiletbesgSlider
        function AntaldagligeToiletbesgSliderValueChanging(app, event)
            %Gem den afrundede værdi fra slideren (hver gang slideren bevæges) som LatestValueSliderAntalDagligeToiletbesog
            app.latestValueSliderAntalDagligeToiletbesog = round(event.Value);
            %Sæt LatestValueSliderAntalDagligeToiletbesog som den nuværende
            %værdi for slideren (denne linje kan måske skrottes)
            app.AntaldagligeToiletbesgSlider.Value = app.latestValueSliderAntalDagligeToiletbesog;
            %Opdater spinnerens værdi, når slideren ændres
            app.SpinnerAntalDagligeToiletbesog.Value = app.latestValueSliderAntalDagligeToiletbesog;
            
        end

        % Value changing function: SpinnerAntalDagligeToiletbesog
        function SpinnerAntalDagligeToiletbesogValueChanging(app, event)
            %Gem værdien fra spinneren som som latestValueSpinnerAntalDagligeToiletbesog hver gang den ændres
            latestValueSpinnerAntalDagligeToiletbesog = event.Value;
            %Sæt latestValueSpinnerAntalDagligeToiletbesog som den nuværende værdi for spinneren (denne linje kan måske skrottes)
            app.SpinnerAntalDagligeToiletbesog.Value = latestValueSpinnerAntalDagligeToiletbesog;
            %Opdater sliderens værdi til den seneste værdi fra spinneren
            app.AntaldagligeToiletbesgSlider.Value = latestValueSpinnerAntalDagligeToiletbesog;
            
        end

        % Value changing function: InkontinensSlider
        function InkontinensSliderValueChanging(app, event)
            %Gem den afrundede værdi fra slideren (hver gang slideren
            %bevæges) som LatestValueSliderAntalInkontinensEpisoder
            app.latestValueSliderInkontinensEpisoder = round(event.Value);
            %Sæt latestValueSliderInkontinensEpisoder som den nuværende
            %værdi for slideren (denne linje kan måske skrottes)
            app.InkontinensSlider.Value = app.latestValueSliderInkontinensEpisoder;
            %Opdater spinnerens værdi, når slideren ændres
            app.SpinnerAntalInkontinensEpisoder.Value = app.latestValueSliderInkontinensEpisoder;
            
        end

        % Value changing function: SpinnerAntalInkontinensEpisoder
        function SpinnerAntalInkontinensEpisoderValueChanging(app, event)
            %Gem værdien fra spinneren som som latestValueSpinnerInkontinens hver gang den ændres
            latestValueSpinnerInkontinens = event.Value;
            %Sæt latestValueSpinnerInkontinens som den nuværende værdi for spinneren (denne linje kan måske skrottes)
            app.InkontinensSlider.Value = latestValueSpinnerInkontinens;
            %Opdater sliderens værdi til den seneste værdi fra spinneren
            app.SpinnerAntalInkontinensEpisoder.Value = latestValueSpinnerInkontinens;
            
        end

        % Value changing function: AntalnatligeToiletbesgSlider
        function AntalnatligeToiletbesgSliderValueChanging(app, event)
            %Gem den afrundede værdi fra slideren (hver gang slideren bevæges) som LatestValueSliderAntalNatligeToiletbesog
            app.latestValueSliderAntalNatligeToiletbesog = round(event.Value);
            %Sæt LatestValueSliderAntalNatligeToiletbesog som den nuværende
            %værdi for slideren (denne linje kan måske skrottes)
            app.AntalnatligeToiletbesgSlider.Value = app.latestValueSliderAntalNatligeToiletbesog;
            %Opdater spinnerens værdi, når slideren ændres
            app.SpinnerAntalNatligeToiletbesog.Value = app.latestValueSliderAntalNatligeToiletbesog;
            
        end

        % Value changing function: SpinnerAntalNatligeToiletbesog
        function SpinnerAntalNatligeToiletbesogValueChanging(app, event)
            %Gem værdien fra spinneren som som latestValueSpinnerAntalNatligeToiletbesog hver gang den ændres
            latestValueSpinnerAntalNatligeToiletbesog = event.Value;
            %Sæt latestValueSpinnerAntalNatligeToiletbesog som den nuværende værdi for spinneren (denne linje kan måske skrottes)
            app.SpinnerAntalNatligeToiletbesog.Value = latestValueSpinnerAntalNatligeToiletbesog;
            %Opdater sliderens værdi til den seneste værdi fra spinneren
            app.AntalnatligeToiletbesgSlider.Value = latestValueSpinnerAntalNatligeToiletbesog;
            
        end

        % Button pushed function: VisanbefalingButton
        function VisanbefalingButtonPushed(app, event)

            %% Gem indtastede værdier i variable

            app.CPR = str2double(app.CPRnrEditField.Value)


            app.x_Age = str2double(app.AlderEditField.Value)
            
            
            %Undersøger værdien af switchen, som bruges til at indtaste køn
            ValueGender = app.SwitchGender.Value;
            
            %strcmp (compare string): Hvis ValueGender er lig med 'Mand',
            %så skal X_Gender være lig med 0
            if strcmp(ValueGender,'Mand')
               app.x_Gender = 0
            end
            
            
            %strcmp (compare string): Hvis ValueGender er lig med 'Kvinde',
            %så skal X_Gender være lig med 1
            if strcmp(ValueGender,'Kvinde')
                app.x_Gender = 1
            end
            
            %Undersøger værdien af switchen, som bruges til at indtaste
            %hvorvidt der opleves trang eller ej
            ValueUrge = app.SwitchUrge.Value;
            
            %strcmp (compare string): Hvis ValueTrang er lig med 'Ja',
            %så skal X_Urge være lig med 1
            if strcmp(ValueUrge,'Ja')
                  app.x_Urge = 1;
            end
            
            %strcmp (compare string): Hvis ValueTrang er lig med 'Nej',
            %så skal X_Urge være lig med 0
            if strcmp(ValueUrge,'Nej')
                app.x_Urge = 0;
            end
            
                               
            app.x_BMI = str2double(app.BMIEditField.Value)


            %app.x_Frequency = app.AntaldagligeToiletbesgSlider.Value
            app.x_Frequency = app.latestValueSliderAntalDagligeToiletbesog

           % app.x_Leakage = app.InkontinensSlider.Value
            app.x_Leakage = app.latestValueSliderInkontinensEpisoder 
            
            %app.x_Nocturia = app.AntalnatligeToiletbesgSlider.Value
            app.x_Nocturia = app.latestValueSliderAntalNatligeToiletbesog


            %% Model 1: Urge(Paradigme)

            X1 = [ones(size(app.data.Gender)) app.data.Gender app.data.Age app.data.BMI app.data.Urge app.data.Frequency app.data.Leakage app.data.Nocturia];
            y1 = app.data.Urge_Paradigme;

            b1 = regress(y1,X1);

            % Opstil funktionsforskrift for regressionen

            app.YFIT_1 = app.x_Gender*b1(2) + app.x_Age*b1(3) + app.x_BMI*b1(4) + app.x_Urge*b1(5) + app.x_Frequency*b1(6) + app.x_Leakage*b1(7) + app.x_Nocturia*b1(8) + b1(1);


            %% Model 2: Limited(Paradigme)

            X2 = [ones(size(app.data.Gender)) app.data.Gender app.data.Age app.data.BMI app.data.Urge app.data.Frequency app.data.Leakage app.data.Nocturia]; % ones(size(data.Gender)) indsætter en række af 1-taller som første søjle i matricen. Dette gør, at det første tal i b-vektoren (outputtet) er skæringskoefficienten.
            y2 = app.data.Limited_Paradigme;

            b2 = regress(y2,X2);

            app.YFIT_2 = app.x_Gender*b2(2) + app.x_Age*b2(3) + app.x_BMI*b2(4) + app.x_Urge*b2(5) + app.x_Frequency*b2(6) + app.x_Leakage*b2(7) + app.x_Nocturia*b2(8) + b2(1);


            %% Model 3: Sessionstid(Limited)

            X3 = [ones(size(app.data.Gender)) app.data.Gender app.data.Age app.data.BMI app.data.Urge app.data.Frequency app.data.Leakage app.data.Nocturia app.data.Urge_Paradigme app.data.Limited_Paradigme];
            y3 = app.data.Session_Limited;

            b3 = regress(y3,X3);

            app.YFIT_3 = app.x_Gender*b3(2) + app.x_Age*b3(3) + app.x_BMI*b3(4) + app.x_Urge*b3(5) + app.x_Frequency*b3(6) + app.x_Leakage*b3(7) + app.x_Nocturia*b3(8) + app.YFIT_1*b3(9) + app.YFIT_2*b3(10) + b3(1);


            %% Model 4: Intensity(Urge)

            X4 = [ones(size(app.data.Gender)) app.data.Gender app.data.Age app.data.BMI app.data.Urge app.data.Frequency app.data.Leakage app.data.Nocturia app.data.Urge_Paradigme app.data.Limited_Paradigme];
            y4 = app.data.Intensity_Urge;

            b4 = regress(y4,X4);

            app.YFIT_4 = app.x_Gender*b4(2) + app.x_Age*b4(3) + app.x_BMI*b4(4) + app.x_Urge*b4(5) + app.x_Frequency*b4(6) + app.x_Leakage*b4(7) + app.x_Nocturia*b4(8) + app.YFIT_1*b4(9) + app.YFIT_2*b4(10) + b4(1);


            %% Model 5: Intensity(Limited)

            X5 = [ones(size(app.data.Gender)) app.data.Gender app.data.Age app.data.BMI app.data.Urge app.data.Frequency app.data.Leakage app.data.Nocturia app.data.Urge_Paradigme app.data.Limited_Paradigme];
            y5 = app.data.Intensity_Limited;

            b5 = regress(y5,X5);

            app.YFIT_5 = app.x_Gender*b5(2) + app.x_Age*b5(3) + app.x_BMI*b5(4) + app.x_Urge*b5(5) + app.x_Frequency*b5(6) + app.x_Leakage*b5(7) + app.x_Nocturia*b5(8) + app.YFIT_1*b5(9) + app.YFIT_2*b5(10) + b5(1);

            % Visualisering af resultater
            app.VisAnbefaling1.Value = app.YFIT_1;
            app.VisAnbefaling1.Visible = 'on';

            app.VisAnbefaling2.Value = app.YFIT_2;
            app.VisAnbefaling2.Visible = 'on';

            app.VisAnbefaling3.Value = app.YFIT_3;
            app.VisAnbefaling3.Visible = 'on';

            app.VisAnbefaling4.Value = app.YFIT_4;
            app.VisAnbefaling4.Visible = 'on';

            app.VisAnbefaling5.Value = app.YFIT_5;
            app.VisAnbefaling5.Visible = 'on';

        end

        % Button pushed function: GemButton
        function GemButtonPushed(app, event)
            qans = questdlg('Er du sikker på, at du vil gemme?','Gem patient','Ja','Nej','Ja');

            if isempty(qans)|| strcmp(qans, 'Ja')

                gemData = table(app.CPR,app.x_Gender,app.x_Age,app.x_BMI,app.x_Urge,app.x_Frequency,app.x_Leakage,app.x_Nocturia,app.YFIT_1,app.YFIT_2,app.YFIT_3,app.YFIT_4,app.YFIT_5,'VariableNames',{'CPR' 'Gender' 'Age' 'BMI' 'Urge' 'Frequency' 'Leakage' 'Nocturia' 'Urge_Paradigme' 'Limited_Paradigme' 'Session_Limited' 'Intensity_Urge' 'Intensity_Limited'});

                sqlwrite(app.conn,'MidlertidigDatabase',gemData)
            end

            if isempty(qans)|| strcmp(qans, 'Nej')
                return
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LogudButton = uibutton(app.UIFigure, 'push');
            app.LogudButton.ButtonPushedFcn = createCallbackFcn(app, @LogudButtonPushed, true);
            app.LogudButton.Text = 'Log ud ';
            app.MenuButton = uibutton(app.UIFigure, 'push');
            app.MenuButton.ButtonPushedFcn = createCallbackFcn(app, @MenuButtonPushed, true);
            app.MenuButton.Text = {'Menu'; ''};
            app.OprettelseafnypatientLabel = uilabel(app.UIFigure);
            app.OprettelseafnypatientLabel.Text = {'Oprettelse af ny patient'; ''};
            app.SwitchGender = uiswitch(app.UIFigure, 'slider');
            app.SwitchGender.Items = {'Kvinde', 'Mand'};
            app.SwitchGender.Value = 'Kvinde';
            app.KnLabel = uilabel(app.UIFigure);
            app.KnLabel.Text = 'Køn:';
            app.TrangLabel = uilabel(app.UIFigure);
            app.TrangLabel.Text = 'Trang:';
            app.AntaldagligeToiletbesgSliderLabel = uilabel(app.UIFigure);
            app.AntaldagligeToiletbesgSliderLabel.Text = {'Antal daglige: '; 'Toiletbesøg'};
            app.AntaldagligeToiletbesgSlider = uislider(app.UIFigure);
            app.AntaldagligeToiletbesgSlider.Limits = [0 30];
            app.AntaldagligeToiletbesgSlider.MajorTicks = [0 5 10 15 20 25 30];
            app.AntaldagligeToiletbesgSlider.ValueChangingFcn = createCallbackFcn(app, @AntaldagligeToiletbesgSliderValueChanging, true);
            app.AntaldagligeToiletbesgSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30];
            app.InkontinensSliderLabel = uilabel(app.UIFigure);
            app.InkontinensSliderLabel.Text = 'Inkontinens:';
            app.InkontinensSlider = uislider(app.UIFigure);
            app.InkontinensSlider.Limits = [0 20];
            app.InkontinensSlider.MajorTicks = [0 4 8 12 16 20];
            app.InkontinensSlider.ValueChangingFcn = createCallbackFcn(app, @InkontinensSliderValueChanging, true);
            app.InkontinensSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.AntalnatligeToiletbesgSliderLabel = uilabel(app.UIFigure);
            app.AntalnatligeToiletbesgSliderLabel.Text = {'Antal natlige: '; 'Toiletbesøg'};
            app.AntalnatligeToiletbesgSlider = uislider(app.UIFigure);
            app.AntalnatligeToiletbesgSlider.Limits = [0 20];
            app.AntalnatligeToiletbesgSlider.MajorTicks = [0 4 8 12 16 20];
            app.AntalnatligeToiletbesgSlider.MajorTickLabels = {'0', '4', '8 ', '12', '16', '20', ''};
            app.AntalnatligeToiletbesgSlider.ValueChangingFcn = createCallbackFcn(app, @AntalnatligeToiletbesgSliderValueChanging, true);
            app.AntalnatligeToiletbesgSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.VisanbefalingButton = uibutton(app.UIFigure, 'push');
            app.VisanbefalingButton.ButtonPushedFcn = createCallbackFcn(app, @VisanbefalingButtonPushed, true);
            app.VisanbefalingButton.Text = 'Vis anbefaling';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.GemButton = uibutton(app.UIFigure, 'push');
            app.GemButton.ButtonPushedFcn = createCallbackFcn(app, @GemButtonPushed, true);
            app.GemButton.Text = {'Gem'; ''};
            app.StimulationsanbefalingLabel = uilabel(app.UIFigure);
            app.StimulationsanbefalingLabel.Text = {'Stimulationsanbefaling'; ''};
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.TrangparadigmeLabel = uilabel(app.UIFigure);
            app.TrangparadigmeLabel.Text = {'Trang paradigme:'; ''};
            app.TidsbegrnsetparadigmeLabel = uilabel(app.UIFigure);
            app.TidsbegrnsetparadigmeLabel.Text = {'Tidsbegrænset paradigme:'; ''};
            app.IntensitettrangLabel = uilabel(app.UIFigure);
            app.IntensitettrangLabel.Text = 'Intensitet (trang):';
            app.SessionstidLabel = uilabel(app.UIFigure);
            app.SessionstidLabel.Text = 'Sessionstid:';
            app.IntensitettidsbegrnsetLabel = uilabel(app.UIFigure);
            app.IntensitettidsbegrnsetLabel.Text = 'Intensitet (tidsbegrænset):';
            app.SwitchUrge = uiswitch(app.UIFigure, 'slider');
            app.SwitchUrge.Items = {'Ja', 'Nej'};
            app.SwitchUrge.Value = 'Ja';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = {''; ''};
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '';
            app.VisAnbefaling1 = uieditfield(app.UIFigure, 'numeric');
            app.VisAnbefaling2 = uieditfield(app.UIFigure, 'numeric');
            app.VisAnbefaling3 = uieditfield(app.UIFigure, 'numeric');
            app.VisAnbefaling4 = uieditfield(app.UIFigure, 'numeric');
            app.VisAnbefaling5 = uieditfield(app.UIFigure, 'numeric');
            app.CPRnrEditFieldLabel = uilabel(app.UIFigure);
            app.CPRnrEditFieldLabel.Text = 'CPR-nr.:';
            app.CPRnrEditField = uieditfield(app.UIFigure, 'text');
            app.SpinnerAntalDagligeToiletbesog = uispinner(app.UIFigure);
            app.SpinnerAntalDagligeToiletbesog.ValueChangingFcn = createCallbackFcn(app, @SpinnerAntalDagligeToiletbesogValueChanging, true);
            app.SpinnerAntalInkontinensEpisoder = uispinner(app.UIFigure);
            app.SpinnerAntalInkontinensEpisoder.ValueChangingFcn = createCallbackFcn(app, @SpinnerAntalInkontinensEpisoderValueChanging, true);
            app.SpinnerAntalNatligeToiletbesog = uispinner(app.UIFigure);
            app.SpinnerAntalNatligeToiletbesog.ValueChangingFcn = createCallbackFcn(app, @SpinnerAntalNatligeToiletbesogValueChanging, true);
            app.AlderEditField_2Label = uilabel(app.UIFigure);
            app.AlderEditField_2Label.Text = 'Alder:';
            app.AlderEditField = uieditfield(app.UIFigure, 'text');
            app.BMIEditField_2Label = uilabel(app.UIFigure);
            app.BMIEditField_2Label.Text = 'BMI:';
            app.BMIEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_ny_patient

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end