classdef GUI_evaluering < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        evalFrequencyField              matlab.ui.control.NumericEditField
        evalUrgeField                   matlab.ui.control.NumericEditField
        evalNocturiaField               matlab.ui.control.NumericEditField
        evalLeakageField                matlab.ui.control.NumericEditField
        evalUrgePField                  matlab.ui.control.NumericEditField
        evalLimitedPField               matlab.ui.control.NumericEditField
        evalSessionField                matlab.ui.control.NumericEditField
        evalUrgeIField                  matlab.ui.control.NumericEditField
        evalLimitedIField               matlab.ui.control.NumericEditField
        Label_12                        matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        Label_13                        matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        mALabel_2                       matlab.ui.control.Label
        mALabel                         matlab.ui.control.Label
        CPRnrEditField_2                matlab.ui.control.EditField
        CPRnrEditField_2Label           matlab.ui.control.Label
        IntensitettidsbegrnsetLabel     matlab.ui.control.Label
        IntensitettrangLabel            matlab.ui.control.Label
        SessionstidLabel                matlab.ui.control.Label
        TidsbegrnsetparadigmeLabel      matlab.ui.control.Label
        TrangparadigmeLabel             matlab.ui.control.Label
        StimulationsanbefalingLabel     matlab.ui.control.Label
        prdgnLabel_3                    matlab.ui.control.Label
        prdgnLabel_2                    matlab.ui.control.Label
        prdgnLabel                      matlab.ui.control.Label
        NykturiLabel                    matlab.ui.control.Label
        InkontinensLabel                matlab.ui.control.Label
        FrekvensLabel                   matlab.ui.control.Label
        TrangLabel                      matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        evalBMIField                    matlab.ui.control.NumericEditField
        evalAgeField                    matlab.ui.control.NumericEditField
        evalGenderField                 matlab.ui.control.NumericEditField
        IkkesuccesfuldButton            matlab.ui.control.Button
        SuccesfuldButton                matlab.ui.control.Button
        VarbehandlingensuccesfuldLabel  matlab.ui.control.Label
        TidligeresymptomerLabel         matlab.ui.control.Label
        HentinformationButton           matlab.ui.control.Button
        IndtastCPRnrLabel               matlab.ui.control.Label
        EvalueringLabel                 matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        MenuButton                      matlab.ui.control.Button
        Label_5                         matlab.ui.control.Label
        LogudButton                     matlab.ui.control.Button
        Label_3                         matlab.ui.control.Label
    end

    
    properties (Access = public)    
        % Forbindelse til databasen
        conn
        data
        dataMidlertidig
        
        pullData
        evalData
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Opret forbindelse til databasen
            app.conn = database('hst_2022st6403','hst_2022st6403','gaevaepheimeicoogexe'); % OBS: første streng er det navn, man selv har givet databasen ved oprettelsen

            % Træk data fra algoritme databasen
            selectAlgoritmeDatabase = 'SELECT * FROM AlgoritmeDatabase';
            app.data = select(app.conn,selectAlgoritmeDatabase);

            % Træk data fra den midlertidige database
            selectMidlertidigDatabase = 'SELECT * FROM MidlertidigDatabase';
            app.dataMidlertidig = select(app.conn,selectMidlertidigDatabase);
            
            % Slå outputfelter fra
            app.evalGenderField.Visible = 'off';          
            app.evalAgeField.Visible = 'off';
            app.evalBMIField.Visible = 'off';
            app.evalUrgeField.Visible = 'off';
            app.evalFrequencyField.Visible = 'off';
            app.evalLeakageField.Visible = 'off';
            app.evalNocturiaField.Visible = 'off';
            app.evalUrgePField.Visible = 'off';
            app.evalLimitedPField.Visible = 'off';
            app.evalSessionField.Visible = 'off';
            app.evalUrgeIField.Visible = 'off';
            app.evalLimitedIField.Visible = 'off'; 
    
        end

        % Button pushed function: LogudButton
        function LogudButtonPushed(app, event)
            qans = questdlg('Er du sikker på, at du vil logge ud?','Log ud','Ja','Nej','Ja');
            
            if isempty(qans)|| strcmp(qans, 'Ja')
                GUI_login_brugergraense
                app.UIFigure.Visible = 'off';
                return
            end
        end

        % Button pushed function: MenuButton
        function MenuButtonPushed(app, event)
            qans = questdlg('Er du sikker på, at du vil gå til menu?','Tilbage til menu','Ja','Nej','Ja');
            
            if isempty(qans)|| strcmp(qans, 'Ja')
                GUI_vaelg_brug
                app.UIFigure.Visible = 'off';
                return
            end
        end

        % Button pushed function: SuccesfuldButton
        function SuccesfuldButtonPushed(app, event)
            qans = questdlg('Er du sikker på, at behandlingen var succesfuld?','Evaluering af behandling','Ja','Nej','Ja');
            
            if isempty(qans)|| strcmp(qans, 'Ja')
                
                % Flytning af data til algoritme database (ved succesfuld evaluering)
                approvedData = table(app.evalData.Gender, app.evalData.Age, app.evalData.BMI, app.evalData.Urge, app.evalData.Frequency, app.evalData.Leakage, app.evalData.Nocturia, app.evalData.Urge_Paradigme, app.evalData.Limited_Paradigme, app.evalData.Session_Limited, app.evalData.Intensity_Urge, app.evalData.Intensity_Limited,'VariableNames',{'Gender' 'Age' 'BMI' 'Urge' 'Frequency' 'Leakage' 'Nocturia' 'Urge_Paradigme' 'Limited_Paradigme' 'Session_Limited' 'Intensity_Urge' 'Intensity_Limited'});

                sqlwrite(app.conn,'AlgoritmeDatabase',approvedData)
                
                GUI_vaelg_brug
                app.UIFigure.Visible = 'off';
                return
            end
        end

        % Button pushed function: IkkesuccesfuldButton
        function IkkesuccesfuldButtonPushed(app, event)
            qans = questdlg('Er du sikker på, at behandlingen ikke var succesfuld?','Evaluering af behandling','Ja','Nej','Ja');
            
            if isempty(qans)|| strcmp(qans, 'Ja')
                GUI_ny_patient
                app.UIFigure.Visible = 'off';
                return
            end
        end

        % Button pushed function: HentinformationButton
        function HentinformationButtonPushed(app, event)
            % Hent specifik data på baggrund af CPR
            app.pullData = app.dataMidlertidig.CPR == str2double(app.CPRnrEditField_2.Value);
            app.evalData = app.dataMidlertidig(app.pullData,:);
            
            % Visualisering af patientinformation            
            evalUrge = double(app.evalData.Urge);
            app.evalUrgeField.Value = evalUrge;
            app.evalUrgeField.Visible = 'on'; 
            
            evalFrequency = double(app.evalData.Frequency);
            app.evalFrequencyField.Value = evalFrequency;
            app.evalFrequencyField.Visible = 'on'; 
            
            evalLeakage = double(app.evalData.Leakage);
            app.evalLeakageField.Value = evalLeakage;
            app.evalLeakageField.Visible = 'on'; 
            
            evalNocturia = double(app.evalData.Nocturia);
            app.evalNocturiaField.Value = evalNocturia;
            app.evalNocturiaField.Visible = 'on';
            
            evalUrgeP = double(app.evalData.Urge_Paradigme);
            app.evalUrgePField.Value = evalUrgeP;
            app.evalUrgePField.Visible = 'on';
 
            evalLimitedP = double(app.evalData.Limited_Paradigme);
            app.evalLimitedPField.Value = evalLimitedP;
            app.evalLimitedPField.Visible = 'on'; 

            evalSession = double(app.evalData.Session_Limited);
            app.evalSessionField.Value = evalSession;
            app.evalSessionField.Visible = 'on'; 
            
            evalUrgeI = double(app.evalData.Intensity_Urge);
            app.evalUrgeIField.Value = evalUrgeI;
            app.evalUrgeIField.Visible = 'on';
            
            evalLimitedI = double(app.evalData.Intensity_Limited);
            app.evalLimitedIField.Value = evalLimitedI;
            app.evalLimitedIField.Visible = 'on'; 

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.LogudButton = uibutton(app.UIFigure, 'push');
            app.LogudButton.ButtonPushedFcn = createCallbackFcn(app, @LogudButtonPushed, true);
            app.LogudButton.Text = 'Log ud ';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.MenuButton = uibutton(app.UIFigure, 'push');
            app.MenuButton.ButtonPushedFcn = createCallbackFcn(app, @MenuButtonPushed, true);
            app.MenuButton.Text = {'Menu'; ''};
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '';
            app.EvalueringLabel = uilabel(app.UIFigure);
            app.EvalueringLabel.Text = {'Evaluering'; ''; ''};
            app.IndtastCPRnrLabel = uilabel(app.UIFigure);
            app.IndtastCPRnrLabel.Text = 'Indtast CPR-nr.';
            app.HentinformationButton = uibutton(app.UIFigure, 'push');
            app.HentinformationButton.ButtonPushedFcn = createCallbackFcn(app, @HentinformationButtonPushed, true);
            app.HentinformationButton.Text = {'Hent information'; ''};
            app.TidligeresymptomerLabel = uilabel(app.UIFigure);
            app.TidligeresymptomerLabel.Text = {'Tidligere symptomer:'; ''};
            app.VarbehandlingensuccesfuldLabel = uilabel(app.UIFigure);
            app.VarbehandlingensuccesfuldLabel.Text = 'Var behandlingen succesfuld?';
            app.SuccesfuldButton = uibutton(app.UIFigure, 'push');
            app.SuccesfuldButton.ButtonPushedFcn = createCallbackFcn(app, @SuccesfuldButtonPushed, true);
            app.SuccesfuldButton.Text = {'Succesfuld'; ''};
            app.IkkesuccesfuldButton = uibutton(app.UIFigure, 'push');
            app.IkkesuccesfuldButton.ButtonPushedFcn = createCallbackFcn(app, @IkkesuccesfuldButtonPushed, true);
            app.IkkesuccesfuldButton.Text = {'Ikke-succesfuld'; ''};
            app.evalGenderField = uieditfield(app.UIFigure, 'numeric');
            app.evalAgeField = uieditfield(app.UIFigure, 'numeric');
            app.evalBMIField = uieditfield(app.UIFigure, 'numeric');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.TrangLabel = uilabel(app.UIFigure);
            app.TrangLabel.Text = 'Trang:';
            app.FrekvensLabel = uilabel(app.UIFigure);
            app.FrekvensLabel.Text = 'Frekvens:';
            app.InkontinensLabel = uilabel(app.UIFigure);
            app.InkontinensLabel.Text = 'Inkontinens:';
            app.NykturiLabel = uilabel(app.UIFigure);
            app.NykturiLabel.Text = 'Nykturi:';
            app.prdgnLabel = uilabel(app.UIFigure);
            app.prdgnLabel.Text = 'pr. døgn';
            app.prdgnLabel_2 = uilabel(app.UIFigure);
            app.prdgnLabel_2.Text = 'pr. døgn';
            app.prdgnLabel_3 = uilabel(app.UIFigure);
            app.prdgnLabel_3.Text = 'pr. døgn';
            app.StimulationsanbefalingLabel = uilabel(app.UIFigure);
            app.StimulationsanbefalingLabel.Text = {'Stimulationsanbefaling:'; ''};
            app.TrangparadigmeLabel = uilabel(app.UIFigure);
            app.TrangparadigmeLabel.Text = {'Trang:'; '(paradigme)'};
            app.TidsbegrnsetparadigmeLabel = uilabel(app.UIFigure);
            app.TidsbegrnsetparadigmeLabel.Text = {'Tidsbegrænset:'; '(paradigme)'; ''};
            app.SessionstidLabel = uilabel(app.UIFigure);
            app.SessionstidLabel.Text = 'Sessionstid:';
            app.IntensitettrangLabel = uilabel(app.UIFigure);
            app.IntensitettrangLabel.Text = {'Intensitet (trang):'; ''};
            app.IntensitettidsbegrnsetLabel = uilabel(app.UIFigure);
            app.IntensitettidsbegrnsetLabel.Text = {'Intensitet:'; '(tidsbegrænset)'};
            app.CPRnrEditField_2Label = uilabel(app.UIFigure);
            app.CPRnrEditField_2Label.Text = 'CPR-nr.:';
            app.CPRnrEditField_2 = uieditfield(app.UIFigure, 'text');
            app.mALabel = uilabel(app.UIFigure);
            app.mALabel.Text = 'mA ';
            app.mALabel_2 = uilabel(app.UIFigure);
            app.mALabel_2.Text = 'mA ';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '';
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '';
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '';
            app.evalLimitedIField = uieditfield(app.UIFigure, 'numeric');
            app.evalUrgeIField = uieditfield(app.UIFigure, 'numeric');
            app.evalSessionField = uieditfield(app.UIFigure, 'numeric');
            app.evalLimitedPField = uieditfield(app.UIFigure, 'numeric');
            app.evalUrgePField = uieditfield(app.UIFigure, 'numeric');
            app.evalLeakageField = uieditfield(app.UIFigure, 'numeric');
            app.evalNocturiaField = uieditfield(app.UIFigure, 'numeric');
            app.evalUrgeField = uieditfield(app.UIFigure, 'numeric');
            app.evalFrequencyField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_evaluering

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end