classdef GUI_login_brugergraense < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ForkerteLoginoplysningerLabel  matlab.ui.control.Label
        LoginButton                    matlab.ui.control.Button
        PasswordField                  matlab.ui.control.EditField
        UsernameField                  matlab.ui.control.EditField
        VelkommentilUTreatmentLabel    matlab.ui.control.Label
    end


    properties (Access = public)
        % Forbindelse
        conn
        dataPersonale
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ForkerteLoginoplysningerLabel.Visible = 'off';

            % Opret database kommunikation
            app.conn = database('hst_2022st6403','hst_2022st6403','gaevaepheimeicoogexe'); % OBS: første streng er det navn, man selv har givet databasen ved oprettelsen

            % Træk data fra personale databasen
            selectPersonaleDatabase = 'SELECT * FROM PersonaleDatabase';
            app.dataPersonale = select(app.conn,selectPersonaleDatabase);

        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            
            % Definer input i teksfelter
            username = str2double(app.UsernameField.Value);
            password = str2double(app.PasswordField.Value);
            
            idx = app.dataPersonale.Brugernavn == username & app.dataPersonale.Kodeord == password; 
            
            if sum(idx) == 1
                GUI_vaelg_brug
                app.UIFigure.Visible = 'off';
            else
                app.ForkerteLoginoplysningerLabel.Visible = 'on';
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VelkommentilUTreatmentLabel = uilabel(app.UIFigure);
            app.VelkommentilUTreatmentLabel.Text = {'Velkommen til UTreatment'; ''};
            app.UsernameField = uieditfield(app.UIFigure, 'text');
            app.UsernameField.Value = 'Brugernavn';
            app.PasswordField = uieditfield(app.UIFigure, 'text');
            app.PasswordField.Value = 'Adgangskode';
            app.LoginButton = uibutton(app.UIFigure, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = {'Login'; ''};
            app.ForkerteLoginoplysningerLabel = uilabel(app.UIFigure);
            app.ForkerteLoginoplysningerLabel.Text = 'Forkerte loginoplysninger - indtast på ny';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_login_brugergraense

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end