classdef GUI_vaelg_brug < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        VlgnafflgendemulighederLabel  matlab.ui.control.Label
        LogudButton                   matlab.ui.control.Button
        EvalueringButton              matlab.ui.control.Button
        NypatientButton               matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: NypatientButton
        function NypatientButtonPushed(app, event)
           GUI_ny_patient
           app.UIFigure.Visible = 'off';
        end

        % Button pushed function: EvalueringButton
        function EvalueringButtonPushed(app, event)
            GUI_evaluering
            app.UIFigure.Visible = 'off';
        end

        % Button pushed function: LogudButton
        function LogudButtonPushed(app, event)
             qans = questdlg('Er du sikker på, at du vil logge ud?','Log ud','Ja','Nej','Ja');
           
           if isempty(qans)|| strcmp(qans, 'Ja') 
                GUI_login_brugergraense
                app.UIFigure.Visible = 'off';
               return
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NypatientButton = uibutton(app.UIFigure, 'push');
            app.NypatientButton.ButtonPushedFcn = createCallbackFcn(app, @NypatientButtonPushed, true);
            app.NypatientButton.Text = 'Ny patient';
            app.EvalueringButton = uibutton(app.UIFigure, 'push');
            app.EvalueringButton.ButtonPushedFcn = createCallbackFcn(app, @EvalueringButtonPushed, true);
            app.EvalueringButton.Text = {'Evaluering'; ''};
            app.LogudButton = uibutton(app.UIFigure, 'push');
            app.LogudButton.ButtonPushedFcn = createCallbackFcn(app, @LogudButtonPushed, true);
            app.LogudButton.Text = 'Log ud';
            app.VlgnafflgendemulighederLabel = uilabel(app.UIFigure);
            app.VlgnafflgendemulighederLabel.Text = 'Vælg én af følgende muligheder:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_vaelg_brug

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end